classdef covid_application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19DataVisualizationSourceJohnHopkinsUniversityUIFigure  matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        CountryListBoxLabel     matlab.ui.control.Label
        CountryListBox          matlab.ui.control.ListBox
        StatesListBoxLabel      matlab.ui.control.Label
        StatesListBox           matlab.ui.control.ListBox
        DatatoPlotButtonGroup   matlab.ui.container.ButtonGroup
        CasesButton             matlab.ui.control.RadioButton
        DeathButton             matlab.ui.control.RadioButton
        BothButton              matlab.ui.control.RadioButton
        OptionButtonGroup       matlab.ui.container.ButtonGroup
        CumulativeButton        matlab.ui.control.RadioButton
        DailyButton             matlab.ui.control.RadioButton
        AveragedofdaysLabel_2   matlab.ui.control.Label
        AveragedofdaysSlider    matlab.ui.control.Slider
        StartDateLabel          matlab.ui.control.Label
        StartDateDatePicker     matlab.ui.control.DatePicker
        EndDateDatePickerLabel  matlab.ui.control.Label
        EndDateDatePicker       matlab.ui.control.DatePicker
        DatabaseinfoLabel       matlab.ui.control.Label
        RESETButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        GClass      GrandClass  % GrandClass property that stores everything (main)
        GlobalData  GrandClass  % Stores only global values
        Clist       string      % List of Countries
        CaseAverage  double     % average cases
        DeathAverage double     % average deaths
        PlotDate    datetime    % dates to be plotted (in datetime)
        WholeDate   datetime    % all dates for which database is available
        PlotDatenum             % same as above. in Datenum format
        PlotState   StateClass  % current state instance to be plotted
        CurrCases   double      % cases to be plotted (cumulative or daily)
        CurrDeath   double      % death to be plotted (cumulative or daily)
        CurrDatatoPlot  string  % cases or death or both
        CurrOption   string     % cumulative or daily
        StartDate   datetime    % starting date for plotting
        EndDate     datetime    % end date for plotting
        lowlim      double      % lower limit for plotting x and y axes
        uplim       double      % upper limit for plotting x and y axes
        testvar     double      % testing variable
        
%         To do
%         Both case doesnt work right now - done
%         Give orange colour to death plot - done
        
    end
    
    methods (Access = private)        
        function init(app)
            load covid_data.mat covid_data;
            [r, c] = size(covid_data);
            ovec = repmat(StateClass,r-1,1); % objects vector
            
            for j = 1:c
                for i = 2:r
                    if j == 1 || j == 2 % executed for first two columns
                        switch j
                            case 1
                                ovec(i-1).Countryname = covid_data{i,j};
                            case 2
                                if covid_data{i,j} == ""
                                    ovec(i-1).Statename = "All";
                                else
                                    ovec(i-1).Statename = covid_data{i,j};
                                end
                        end
                        continue;
                    end
                    % executed from 3rd column onwards
                    curr_date = covid_data{1,j};
                    cases = covid_data{i,j}(1);
                    death = covid_data{i,j}(2);
                    ovec(i-1) = insertval(ovec(i-1),curr_date,'date');
                    ovec(i-1) = insertval(ovec(i-1),cases,'cases');
                    ovec(i-1) = insertval(ovec(i-1),death,'death');
                end
            end
            % Compute Daily Cases and Deaths
            for i = 1:length(ovec)
                ovec(i) = ComputeDailyCasesandDeath(ovec(i));
            end
            clist = "Global"; % countries list
            cvec = [];
            for i = 1:length(ovec)
                loc = find(clist==ovec(i).Countryname,1); % location of country name
                if isempty(loc)
                    clist = [clist; ovec(i).Countryname];
                    cvec = [cvec; GrandClass(ovec(i))];
                else
                    cvec(loc-1) = addState(cvec(loc-1),ovec(i));
                end
            end
            t = StateClass("Global","All",0,0); % temporary variable
            t.date = cvec(1).States(1).date; % whole database has the same data entries
            for i=1:length(cvec)
                t.cases = t.cases + cvec(i).States(1).cases;
                t.death = t.death + cvec(i).States(1).death;
            end
            % take global database dates and give it to app.WholeDate
            app.WholeDate = datetime(datenum(t.date,'mm/dd/yy'),'ConvertFrom','datenum'); 
            app.StartDateDatePicker.Value = app.WholeDate(1);   % set initial start date
            app.EndDateDatePicker.Value = app.WholeDate(end);   % set initial end date
            app.EndDate = app.EndDateDatePicker.Value;
            app.StartDate = app.StartDateDatePicker.Value;
            
            % manually compute daily cases and death for globaldata
            t = ComputeDailyCasesandDeath(t);
            app.GlobalData = GrandClass(t);
            app.GClass = [app.GlobalData;cvec];
            app.Clist = clist;
            app.CountryListBox.Items = app.Clist;
            app.CountryListBox.ItemsData = 1:length(app.CountryListBox.Items);
            app.CountryListBox.Value = 1;
            app.CurrDatatoPlot = app.DatatoPlotButtonGroup.SelectedObject.Text;
            app.CurrOption = app.OptionButtonGroup.SelectedObject.Text;
            app.refreshStatesListBox(1);
            app.DateProcessor();
        end
        
        function refreshStatesListBox(app,value)
            % refreshes states list box after country is changed
            app.StatesListBox.Items = app.GClass(value).slist;
            app.StatesListBox.ItemsData = 1:length(app.StatesListBox.Items);
            app.StatesListBox.Value = 1;
            app.plotgraph();
        end
        
        function computeAverage(app,slidervalue)
            i = app.CountryListBox.Value;
            j = app.StatesListBox.Value;
            app.PlotState = app.GClass(i).States(j);
            
            switch app.CurrOption
                case "Daily"
                    app.CurrCases = app.PlotState.dailycases;
                    app.CurrDeath = app.PlotState.dailydeath;
                case "Cumulative"
                    app.CurrCases = app.PlotState.cases;
                    app.CurrDeath = app.PlotState.death;
            end
            app.CaseAverage = movmean(app.CurrCases,[slidervalue-1, 0]);
            app.DeathAverage = movmean(app.CurrDeath,[slidervalue-1, 0]);
        end
        
        function plotgraph(app,slidervalue)
            % plots graph with updated details when called
            if nargin<2
                slidervalue = round(app.AveragedofdaysSlider.Value);
            end
            l = app.lowlim;
            u = app.uplim;
%             if app.testvar==55
%                 disp(); just set a breakpoint here to stop execution.
%                 test var can be placed anywhere
%             end
            app.computeAverage(slidervalue);
            app.PlotDatenum = datenum(app.PlotState.date,'mm/dd/yy');
            app.PlotDate = datetime(app.PlotDatenum,'ConvertFrom','datenum');
            cla(app.UIAxes,"reset");
            switch app.CurrDatatoPlot
                case "Cases"
                    hold(app.UIAxes,"off");
                    bar(app.UIAxes,app.PlotDate(l:u),app.CaseAverage(l:u),'FaceColor','#0072BD');
                    title(app.UIAxes,sprintf("%s %s of %s (%s) - %2.1f day(s) mean",app.CurrOption,app.CurrDatatoPlot,...
                        app.PlotState.Countryname,app.PlotState.Statename,round(slidervalue,1)));
                    grid(app.UIAxes,"on");
                    ylabel(app.UIAxes,sprintf("%s %s",app.CurrOption,app.CurrDatatoPlot));
                    xlabel(app.UIAxes,"Timeline")
                    % try datetick later
                    
                case "Death"
                    hold(app.UIAxes,"off");
                    bar(app.UIAxes,app.PlotDate(l:u),app.DeathAverage(l:u),'FaceColor','#D95319');
                    title(app.UIAxes,sprintf("%s Deaths of %s (%s) - %2.1f day(s) mean",app.CurrOption,...
                        app.PlotState.Countryname,app.PlotState.Statename,round(slidervalue,1)));
                    grid(app.UIAxes,"on");
                    ylabel(app.UIAxes,sprintf("%s Deaths",app.CurrOption));
                    xlabel(app.UIAxes,"Timeline")
                    
                case "Both"
                    hold(app.UIAxes,"off");
                    yyaxis(app.UIAxes,"left")   % left side
                    bar(app.UIAxes,app.PlotDate(l:u),app.CaseAverage(l:u),'FaceColor','#0072BD');
                    hold(app.UIAxes,"on");
                    grid(app.UIAxes,"on");
                    ylabel(app.UIAxes,sprintf("%s Cases",app.CurrOption));
                    
                    yyaxis(app.UIAxes,"right")   % right side
                    plot(app.UIAxes,app.PlotDate(l:u),app.DeathAverage(l:u),'Color','#D95319','LineWidth',2);
                    hold(app.UIAxes,"off");
                    ylabel(app.UIAxes,sprintf("%s Deaths",app.CurrOption));
                    xlabel(app.UIAxes,"Timeline");
                    title(app.UIAxes,sprintf("%s Cases and Deaths of %s (%s) - %2.1f day(s) mean",app.CurrOption,...
                        app.PlotState.Countryname,app.PlotState.Statename,round(slidervalue,1)));
            end
            
        end
        
        function DateProcessor(app)
            % handles processing related to start and end dates
            if app.StartDate < app.WholeDate(1) % cannot go less than initial start date
                app.StartDateDatePicker.Value = app.WholeDate(1);
                app.StartDate = app.StartDateDatePicker.Value;
            end
            if app.EndDate > app.WholeDate(end) % cannot go more than initial end date
                app.EndDateDatePicker.Value = app.WholeDate(end);
                app.EndDate = app.EndDateDatePicker.Value;
            end
            app.lowlim = find(app.StartDate==app.WholeDate);    % lower date limit
            app.uplim = find(app.EndDate==app.WholeDate);       % upper date limit
            app.plotgraph();           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            init(app);  % initialisation
            app.DatabaseinfoLabel.Text = sprintf("Current database is from %s to %s",string(app.WholeDate(1)),string(app.WholeDate(end)));
        end

        % Value changed function: StatesListBox
        function StatesListBoxValueChanged(app, event)
%             value = app.StatesListBox.Value;         
            app.plotgraph();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            value = app.CountryListBox.Value;
            app.refreshStatesListBox(value);
%             app.plotgraph(); % done inside refreshStatesListBox function      
        end

        % Value changing function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanging(app, event)
            changingValue = event.Value;
            if ~isinteger(changingValue)
                changingValue = round(changingValue);
            end
            app.plotgraph(changingValue);
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.CurrOption = app.OptionButtonGroup.SelectedObject.Text;
            app.plotgraph();
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            app.CurrDatatoPlot = app.DatatoPlotButtonGroup.SelectedObject.Text;
            app.plotgraph();
        end

        % Value changed function: StartDateDatePicker
        function StartDateDatePickerValueChanged(app, event)
            app.StartDate = app.StartDateDatePicker.Value;
            app.DateProcessor();
            app.plotgraph();
        end

        % Value changed function: EndDateDatePicker
        function EndDateDatePickerValueChanged(app, event)
            app.EndDate = app.EndDateDatePicker.Value;
            app.DateProcessor();
            app.plotgraph();
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            cla(app.UIAxes,"reset");
            app.CountryListBox.Value = 1;
            app.refreshStatesListBox(1);
            app.AveragedofdaysSlider.Value = 1;
            app.StartDateDatePicker.Value = app.WholeDate(1);
            app.EndDateDatePicker.Value = app.WholeDate(end);
            app.StartDate = app.StartDateDatePicker.Value;
            app.EndDate = app.EndDateDatePicker.Value;
            app.OptionButtonGroup.SelectedObject = app.CumulativeButton;
            app.DatatoPlotButtonGroup.SelectedObject = app.CasesButton;
            app.DateProcessor();
            app.CurrDatatoPlot = app.DatatoPlotButtonGroup.SelectedObject.Text;
            app.CurrOption = app.OptionButtonGroup.SelectedObject.Text;
%             app.testvar = 55;
            app.plotgraph();
        end

        % Value changed function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanged(app, event)
            changedval = app.AveragedofdaysSlider.Value;
            if ~isinteger(changedval)
                changedval = round(changedval);
                app.AveragedofdaysSlider.Value = changedval;
            end
            app.plotgraph(changedval);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19DataVisualizationSourceJohnHopkinsUniversityUIFigure = uifigure('Visible', 'off');
            app.COVID19DataVisualizationSourceJohnHopkinsUniversityUIFigure.Name = 'COVID-19 Data Visualization (Source: John Hopkins University)';
            app.UIAxes = uiaxes(app.COVID19DataVisualizationSourceJohnHopkinsUniversityUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CountryListBoxLabel = uilabel(app.COVID19DataVisualizationSourceJohnHopkinsUniversityUIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.COVID19DataVisualizationSourceJohnHopkinsUniversityUIFigure);
            app.CountryListBox.Items = {};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = {};
            app.StatesListBoxLabel = uilabel(app.COVID19DataVisualizationSourceJohnHopkinsUniversityUIFigure);
            app.StatesListBoxLabel.Text = 'States:';
            app.StatesListBox = uilistbox(app.COVID19DataVisualizationSourceJohnHopkinsUniversityUIFigure);
            app.StatesListBox.Items = {};
            app.StatesListBox.ValueChangedFcn = createCallbackFcn(app, @StatesListBoxValueChanged, true);
            app.StatesListBox.Value = {};
            app.DatatoPlotButtonGroup = uibuttongroup(app.COVID19DataVisualizationSourceJohnHopkinsUniversityUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathButton.Text = 'Death';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.COVID19DataVisualizationSourceJohnHopkinsUniversityUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.AveragedofdaysLabel_2 = uilabel(app.COVID19DataVisualizationSourceJohnHopkinsUniversityUIFigure);
            app.AveragedofdaysLabel_2.Text = {'Averaged'; '# of days'};
            app.AveragedofdaysSlider = uislider(app.COVID19DataVisualizationSourceJohnHopkinsUniversityUIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanged, true);
            app.AveragedofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanging, true);
            app.AveragedofdaysSlider.Value = 1;
            app.StartDateLabel = uilabel(app.COVID19DataVisualizationSourceJohnHopkinsUniversityUIFigure);
            app.StartDateLabel.Text = 'Start Date:';
            app.StartDateDatePicker = uidatepicker(app.COVID19DataVisualizationSourceJohnHopkinsUniversityUIFigure);
            app.StartDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartDateDatePickerValueChanged, true);
            app.StartDateDatePicker.Value = datetime([2020 1 22]);
            app.EndDateDatePickerLabel = uilabel(app.COVID19DataVisualizationSourceJohnHopkinsUniversityUIFigure);
            app.EndDateDatePickerLabel.Text = 'End Date:';
            app.EndDateDatePicker = uidatepicker(app.COVID19DataVisualizationSourceJohnHopkinsUniversityUIFigure);
            app.EndDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EndDateDatePickerValueChanged, true);
            app.DatabaseinfoLabel = uilabel(app.COVID19DataVisualizationSourceJohnHopkinsUniversityUIFigure);
            app.DatabaseinfoLabel.Text = 'Database info';
            app.RESETButton = uibutton(app.COVID19DataVisualizationSourceJohnHopkinsUniversityUIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            % Show the figure after all components are created
            app.COVID19DataVisualizationSourceJohnHopkinsUniversityUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covid_application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID19DataVisualizationSourceJohnHopkinsUniversityUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19DataVisualizationSourceJohnHopkinsUniversityUIFigure)
        end
    end
end