classdef CovidDataVisualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        CountryListBoxLabel        matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        StateorRegionLabel         matlab.ui.control.Label
        StateorRegionListBox       matlab.ui.control.ListBox
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        CasesButton                matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        CumulativeButton           matlab.ui.control.RadioButton
        DailyButton                matlab.ui.control.RadioButton
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        AveragedofdaysSlider       matlab.ui.control.Slider
    end

    
    properties (Access = private)
        Countries % Description
        GlobalCasesCumulative
        GlobalDeathCumulative
        GlobalCasesDaily
        GlobalDeathDaily
        Dates
    end
    
    methods (Access = private)
        
        function PlotData(app, Data, Location, OptDataDeath)
            if nargin == 3
                OptDataDeath = [];
            end
            
            Cumu = "Cumulative";
            Daily = "Daily";
            if app.DatatoPlotButtonGroup.SelectedObject.Text == "Cases"
               cla(app.UIAxes, 'reset')
               if app.AveragedofdaysSlider.Value ~= 1
                   Data = app.Smooth(Data);
               end
               bar(app.UIAxes, app.Dates, Data, 'k')
               datetick(app.UIAxes, 'x', 6, 'keeplimits');
               str = "%s number of cases %s (%s-day window)";
               if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                   title(app.UIAxes, sprintf(str, Cumu, Location, string(app.AveragedofdaysSlider.Value)))
               else
                   title(app.UIAxes, sprintf(str, Daily, Location, string(app.AveragedofdaysSlider.Value)))
               end
               
               
            else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Deaths"
                    cla(app.UIAxes, 'reset')
                    if app.AveragedofdaysSlider.Value ~= 1
                        Data = app.Smooth(Data);
                    end
                    plot(app.UIAxes, app.Dates, Data, 'r')
                    datetick(app.UIAxes, 'x', 6, 'keeplimits');
                    str = "%s number of deaths %s (%s-day window)";
                    if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                        title(app.UIAxes, sprintf(str, Cumu, Location, string(app.AveragedofdaysSlider.Value)))    
                    else
                        title(app.UIAxes, sprintf(str, Daily, Location, string(app.AveragedofdaysSlider.Value)))  
                    end
                    
            else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Both" && nargin == 4
                    cla(app.UIAxes, 'reset')
                    yyaxis(app.UIAxes, 'left')
                    if app.AveragedofdaysSlider.Value ~= 1
                        Data = app.Smooth(Data);
                    end
                    bar(app.UIAxes, app.Dates, Data, 'k')
                    hold(app.UIAxes, 'on')
                    
                    yyaxis(app.UIAxes, 'right')
                    datetick(app.UIAxes, 'x', 6, 'keeplimits');
                    if app.AveragedofdaysSlider.Value ~= 1
                        OptDataDeath = app.Smooth(OptDataDeath);
                    end
                    plot(app.UIAxes, app.Dates, OptDataDeath, 'r')
                    str = "%s number of cases and deaths %s (%s-day window)";
                    if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                        title(app.UIAxes, sprintf(str, Cumu, Location, string(app.AveragedofdaysSlider.Value)))
                    else
                        title(app.UIAxes, sprintf(str, Daily, Location, string(app.AveragedofdaysSlider.Value)))
                    end
                    hold(app.UIAxes, 'off')
                                   
            end
            end
            end
        end
        
        function Data = Smooth(app, Data)
            OldData = Data;
            for i = 1:length(Data)
                if i - (app.AveragedofdaysSlider.Value-1) > 0
                    Data(i) = mean(OldData(i:-1:i-(app.AveragedofdaysSlider.Value-1)));
                else
                    Data(i) = mean(OldData(i:-1:1));
                end      
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load COVIDJHData.mat covid_data;
            
            %%%%%%%%%%%%%%%% Create objects %%%%%%%%%%%%%%%%%%%%%%%
            
            for i = 1:size(covid_data, 1)-1
                CountriesVec(i, 1) = CovidCountriesAndStates(convertCharsToStrings(covid_data{i+1, 1}));
                StatesVec(i, 1) = CovidCountriesAndStates(convertCharsToStrings(covid_data{i+1, 2}));      
            end
            
            %Create vector of unique country objects
            
            indicesNotEqual = [];
            indicesEqual = [];
            
            for i = 1:length(CountriesVec)
                if i == 1
                    indicesNotEqual = [indicesNotEqual i];
                else if CountriesVec(i-1).Country ~= CountriesVec(i).Country
                        indicesNotEqual = [indicesNotEqual i];
                else if CountriesVec(i-1).Country == CountriesVec(i).Country
                        indicesEqual = [indicesEqual i];
                end
                end
                end 
            end
            
            for i = 1:length(indicesNotEqual)
                CountriesVecUnique(i) = CountriesVec(indicesNotEqual(i));
            end


            %%%%%%%%%%%%%%%%% Extract cumulative data %%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            a = 2;
            for i = 1:length(CountriesVec)
                
                %Extracting cumulative data for states only (e.g. not for countries with no
                %states)
                
                for j = 1:size(covid_data, 2)-2
                    if StatesVec(i).Country ~= ""
                        StatesVec(i).DataCumulative = [StatesVec(i).DataCumulative covid_data{i+1, j+2}(1)];
                        StatesVec(i).DataDeathCumu = [StatesVec(i).DataDeathCumu covid_data{i+1, j+2}(2)];
                    end
                end
                %{
                for j = 1:size(covid_data, 2)-2
                    if StatesVec(i).Country ~= ""
                        StatesVec(i).DataCumulative(i, j) = covid_data{i+1, j+2}(1);
                        StatesVec(i).DataDeathCumu(i, j) = covid_data{i+1, j+2}(2);
                    end
                end
                %}
                
                %Extracting cumulative data for all unique countries

                if i == 1
                    for j = 1:size(covid_data, 2)-2
                        CountriesVecUnique(i).DataCumulative = [CountriesVecUnique(i).DataCumulative covid_data{i+1, j+2}(1)];
                        CountriesVecUnique(i).DataDeathCumu = [CountriesVecUnique(i).DataDeathCumu covid_data{i+1, j+2}(2)];
                    end
                
                else if CountriesVec(i-1).Country ~= CountriesVec(i).Country
                        for j = 1:size(covid_data, 2)-2
                            CountriesVecUnique(a).DataCumulative = [CountriesVecUnique(a).DataCumulative covid_data{i+1, j+2}(1)];
                            CountriesVecUnique(a).DataDeathCumu = [CountriesVecUnique(a).DataDeathCumu covid_data{i+1, j+2}(2)];
                        end
                        a = a + 1;
                end
                end
            end
            

            %Set state objects as property of CountriesVecUniq
            

            b = 1;
            c = 1;
            for i = 1:length(CountriesVec)
                if i == 1 
                    continue
                else if CountriesVec(i).Country == CountriesVec(i-1).Country
                        %%%Kacpers stuff%%%%
                        CountriesVec(i-1).States = [CountriesVec(i-1).States StatesVec(i)];
                        j = i;
                        while CountriesVec(i-1).Country == CountriesVec(j+1).Country
                            CountriesVec(i-1).States = [CountriesVec(i-1).States StatesVec(j+1)];
                            j = j + 1;
                        end
                        %%%%%%%%%%%%%%%%%%
                        
                        %{
                        while indicesEqual(c+1) - indicesEqual(c) == 1
                            CountriesVecUnique(indicesEqual(b)-1).States = [CountriesVecUnique(indicesEqual(b)-1).States StatesVec(indicesEqual(c))];
                            c = c + 1;
                        end
                        b = b + 1;
                        %}
                end   
                end
            end
            
            for i = 1:length(CountriesVecUnique)
                for j = 1:length(CountriesVec)
                    if CountriesVec(j).Country == CountriesVecUnique(i).Country
                        CountriesVecUnique(i).States = CountriesVec(j).States;
                        break;
                    end
                end
            end
            
            %Also give countries with no states an empty state object
            for i = 1:length(CountriesVecUnique)
                if isempty(CountriesVecUnique(i).States)
                    CountriesVecUnique(i).States = CovidCountriesAndStates([]);
                end
            end

            %%%%%%%%%%%%%%%%%% Extracting daily data %%%%%%%%%%%%%%%%%%
            
            %Extracting for country objects
            for i = 1:length(CountriesVecUnique)
                for j = 1:size(covid_data, 2)-2
                    if j == 1
                        CountriesVecUnique(i).DataDaily = [CountriesVecUnique(i).DataDaily 0];
                        CountriesVecUnique(i).DataDeathDaily = [CountriesVecUnique(i).DataDeathDaily 0];
                    else 
                        CountriesVecUnique(i).DataDaily = [CountriesVecUnique(i).DataDaily CountriesVecUnique(i).DataCumulative(j)-CountriesVecUnique(i).DataCumulative(j-1)];
                        CountriesVecUnique(i).DataDeathDaily = [CountriesVecUnique(i).DataDeathDaily CountriesVecUnique(i).DataDeathCumu(j)-CountriesVecUnique(i).DataDeathCumu(j-1)];
                    end   
                end
            end
            

            %Extracting for state objects
            for i = 1:length(CountriesVecUnique) 
                    for j = 1:length(CountriesVecUnique(i).States)
                        if ~isempty(CountriesVecUnique(i).States(j).Country)
                            for k = 1:size(covid_data, 2)-2
                                if k == 1
                                    CountriesVecUnique(i).States(j).DataDaily = [CountriesVecUnique(i).States(j).DataDaily 0];
                                    CountriesVecUnique(i).States(j).DataDeathDaily = [CountriesVecUnique(i).States(j).DataDeathDaily 0];
                                else 
                                    CountriesVecUnique(i).States(j).DataDaily = [CountriesVecUnique(i).States(j).DataDaily CountriesVecUnique(i).States(j).DataCumulative(k)-CountriesVecUnique(i).States(j).DataCumulative(k-1)];
                                    CountriesVecUnique(i).States(j).DataDeathDaily = [CountriesVecUnique(i).States(j).DataDeathDaily CountriesVecUnique(i).States(j).DataDeathCumu(k)-CountriesVecUnique(i).States(j).DataDeathCumu(k-1)];
                                end
                            end

                        end
                        
                    end
            end
            
            for i = 1:length(CountriesVecUnique)
                for j = 1:length(CountriesVecUnique(i).States)
                    if ~isempty(CountriesVecUnique(i).States(j).Country)
                        for k = 1:size(covid_data, 2)-2
                            if CountriesVecUnique(i).States(j).DataDaily(k) < 0
                                CountriesVecUnique(i).States(j).DataDaily(k) = 0;
                            else if CountriesVecUnique(i).States(j).DataDeathDaily(k) < 0
                                 CountriesVecUnique(i).States(j).DataDeathDaily(k) = 0;   
                            end
                        end
                        end
                    end
                end
            end
            
            for i = 1:length(CountriesVecUnique)
                for j = 1:length(CountriesVecUnique(i).DataDaily)
                    if CountriesVecUnique(i).DataDaily(j) < 0
                        CountriesVecUnique(i).DataDaily(j) = 0;
                    end
                    if CountriesVecUnique(i).DataDeathDaily(j) < 0
                         CountriesVecUnique(i).DataDeathDaily(j) = 0;
                    end
                end
            end
            
            
            %%%%%%%%%%%%%%%%%%%% Listboxes %%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %Setting up country listbox
            ListOfCountries = ["Global"];
            for i = 1:length(CountriesVecUnique)
                ListOfCountries  = [ListOfCountries CountriesVecUnique(i).Country];
            end
            app.CountryListBox.Items = ListOfCountries;
            
            %Setting up states listbox
            app.StateorRegionListBox.Items = "All";


            %%%%%%%%%%%% Plotting & computing global values %%%%%%%%%%%%%%
            
            GlobalCasesCumulative = [];
            GlobalDeathCumulative = [];
            GlobalCasesDaily = [];
            GlobalDeathDaily = [];
            TempCasesCumu = 0;
            TempDeathCumu = 0;
            for j = 1:size(covid_data, 2)-2
                for i = 1:length(CountriesVecUnique)
                    TempCasesCumu = TempCasesCumu + CountriesVecUnique(i).DataCumulative(1, j);
                    TempDeathCumu = TempDeathCumu + CountriesVecUnique(i).DataDeathCumu(1, j);
                end
                GlobalCasesCumulative = [GlobalCasesCumulative TempCasesCumu];
                GlobalDeathCumulative = [GlobalDeathCumulative TempDeathCumu];
                TempCasesCumu = 0;
                TempDeathCumu = 0;
            end
            
            for i = 1:length(GlobalCasesCumulative)
                if i == 1
                    GlobalCasesDaily = [GlobalCasesDaily 0];
                    GlobalDeathDaily = [GlobalDeathDaily 0];
                else
                    GlobalCasesDaily = [GlobalCasesDaily GlobalCasesCumulative(i)-GlobalCasesCumulative(i-1)];
                    GlobalDeathDaily = [GlobalDeathDaily GlobalDeathCumulative(i)-GlobalDeathCumulative(i-1)];  
                end
            end
            

            app.GlobalCasesCumulative = GlobalCasesCumulative;
            app.GlobalDeathCumulative = GlobalDeathCumulative;
            app.GlobalCasesDaily = GlobalCasesDaily;
            app.GlobalDeathDaily = GlobalDeathDaily;


            %covid_data_numbers = covid_data(2:326, 3:193);
            %data = cell2mat(covid_data_numbers);
            %globalcase = [];
            %globaldeath = [];
           
            %for i = 1:382
            %    if mod(i, 2) == 0
            %        globaldeath = [globaldeath, sum(data(1:end, i))];
            %    else
            %        globalcase = [globalcase, sum(data(1:end, i))];
            %    end
            %end
            
            dates = linspace(datenum('01/22/2020'), datenum(covid_data{1, end}), length(GlobalCasesCumulative));
            

            %bar(app.UIAxes, dates, GlobalCasesCumulative); 
            %xlim(app.UIAxes, [datenum(01/22/2020), datenum(covid_data{1,end})]);
            %datetick(app.UIAxes, 'x', 6, 'keeplimits');
            %title(app.UIAxes, "Cumulative number of cases globally")
            app.Dates = dates;
            app.PlotData(GlobalCasesCumulative, "globally");

            app.Countries = CountriesVecUnique;
            %app.Countries(9).States(1)
            %class(app.Countries(9).States(1).Country(1))
            %app.Countries(9).States(2).Country(1)


        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            
            value = app.CountryListBox.Value;
            
            if value == "Global"
                app.StateorRegionListBox.Items = "All";
                
                if app.DatatoPlotButtonGroup.SelectedObject.Text == "Both"
                    if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                        app.PlotData(app.GlobalCasesCumulative, "globally", app.GlobalDeathCumulative)
                    else
                        app.PlotData(app.GlobalCasesDaily, "globally", app.GlobalDeathDaily)
                    end
                        
                end
                
                if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                    app.PlotData(app.GlobalCasesCumulative, "globally")
                else
                    app.PlotData(app.GlobalCasesDaily, "globally")
                end
            else
                for i = 1:length(app.Countries)
                    if app.Countries(i).Country == value
                        index = i;
                    end
                end
                ListOfStates = ["All"];
                ListOfStates = [ListOfStates app.Countries(index).States.Country];
                app.StateorRegionListBox.Items = ListOfStates;
                
                TempLoc = "in %s";
                if app.DatatoPlotButtonGroup.SelectedObject.Text == "Cases"
                    if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                        app.PlotData(app.Countries(index).DataCumulative, sprintf(TempLoc, app.Countries(index).Country))
                    else
                        app.PlotData(app.Countries(index).DataDaily, sprintf(TempLoc, app.Countries(index).Country))
                    end
                else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Deaths"
                        if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                            app.PlotData(app.Countries(index).DataDeathCumu, sprintf(TempLoc, app.Countries(index).Country))
                        else
                            app.PlotData(app.Countries(index).DataDeathDaily, sprintf(TempLoc, app.Countries(index).Country))
                        end
                else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Both"
                        if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                            app.PlotData(app.Countries(index).DataCumulative, sprintf(TempLoc, app.Countries(index).Country), app.Countries(index).DataDeathCumu)
                        else
                            app.PlotData(app.Countries(index).DataDaily, sprintf(TempLoc, app.Countries(index).Country), app.Countries(index).DataDeathDaily)
                        end
                        
                end
                end
                end
            end
            
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            value = app.StateorRegionListBox.Value;
            if app.StateorRegionListBox.Value ~= "All"
                for i = 1:length(app.Countries)
                    for j = 1:length(app.Countries(i).States)
                        if isempty(app.Countries(i).States(j).Country)
                            break
                        else if app.Countries(i).States(j).Country == convertCharsToStrings(value)
                            TempLoc = "in %s";
                            if app.DatatoPlotButtonGroup.SelectedObject.Text == "Cases"
                                if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                                    app.PlotData(app.Countries(i).States(j).DataCumulative, sprintf(TempLoc, app.Countries(i).States(j).Country));
                                    break
                                else
                                    app.PlotData(app.Countries(i).States(j).DataDaily, sprintf(TempLoc, app.Countries(i).States(j).Country));
                                    break;
                                end
                            else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Deaths"
                                    if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                                        app.PlotData(app.Countries(i).States(j).DataDeathCumu, sprintf(TempLoc, app.Countries(i).States(j).Country));
                                        break
                                    else
                                        app.PlotData(app.Countries(i).States(j).DataDeathDaily, sprintf(TempLoc, app.Countries(i).States(j).Country));
                                        break;
                                    end
                            else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Both"
                                    if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                                        app.PlotData(app.Countries(i).States(j).DataCumulative, sprintf(TempLoc, app.Countries(i).States(j).Country), app.Countries(i).States(j).DataDeathCumu);
                                        break
                                    else
                                        app.PlotData(app.Countries(i).States(j).DataDaily, sprintf(TempLoc, app.Countries(i).States(j).Country), app.Countries(i).States(j).DataDeathDaily);
                                        break
                                    end
                            end
                            end
                            end
                        end
                        end
                    end
                end
            end
            
            if value == "All"
                for i = 1:length(app.Countries)
                    if app.Countries(i).Country == app.CountryListBox.Value
                        index2 = i;
                    end
                end
                ListOfStates = ["All"];
                ListOfStates = [ListOfStates app.Countries(index2).States.Country];
                app.StateorRegionListBox.Items = ListOfStates;
                
                TempLoc = "in %s";
                
                if app.DatatoPlotButtonGroup.SelectedObject.Text == "Cases"
                    if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                        app.PlotData(app.Countries(index2).DataCumulative, sprintf(TempLoc, app.Countries(index2).Country))
                    else
                        app.PlotData(app.Countries(index2).DataDaily, sprintf(TempLoc, app.Countries(index2).Country))
                    end
                else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Deaths"
                        if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                            app.PlotData(app.Countries(index2).DataDeathCumu, sprintf(TempLoc, app.Countries(index2).Country))
                        else
                            app.PlotData(app.Countries(index2).DataDeathDaily, sprintf(TempLoc, app.Countries(index2).Country))
                        end
                else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Both"
                        if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                            app.PlotData(app.Countries(index2).DataCumulative, sprintf(TempLoc, app.Countries(index2).Country), app.Countries(index2).DataDeathCumu)
                        else
                            app.PlotData(app.Countries(index2).DataDaily, sprintf(TempLoc, app.Countries(index2).Country), app.Countries(index2).DataDeathDaily)
                        end
                        
                end
                end
                end
            end


        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            selectedButton = app.DatatoPlotButtonGroup.SelectedObject;
            
            
            if app.StateorRegionListBox.Value == "All"
                if app.CountryListBox.Value == "Global"
                    Gl = "globally";
                    if app.DatatoPlotButtonGroup.SelectedObject.Text == "Cases"
                        if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                            app.PlotData(app.GlobalCasesCumulative, Gl)
                        else
                            app.PlotData(app.GlobalCasesDaily, Gl)
                        end
                    else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Deaths"
                            if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                                app.PlotData(app.GlobalDeathCumulative, Gl)
                            else
                                app.PlotData(app.GlobalDeathDaily, Gl)
                            end
                    else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Both"
                            if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                                app.PlotData(app.GlobalCasesCumulative, Gl, app.GlobalDeathCumulative)
                            else
                                app.PlotData(app.GlobalCasesDaily, Gl, app.GlobalDeathDaily)
                            end
                            
                    end
                    end      
                    end   
                else
                    for i = 1:length(app.Countries)
                        if app.Countries(i).Country == app.CountryListBox.Value
                            index2 = i;
                        end
                    end
                    ListOfStates = ["All"];
                    ListOfStates = [ListOfStates app.Countries(index2).States.Country];
                    app.StateorRegionListBox.Items = ListOfStates;
                
                    TempLoc = "in %s";
                
                    if app.DatatoPlotButtonGroup.SelectedObject.Text == "Cases"
                        if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"    
                            app.PlotData(app.Countries(index2).DataCumulative, sprintf(TempLoc, app.Countries(index2).Country))
                        else
                            app.PlotData(app.Countries(index2).DataDaily, sprintf(TempLoc, app.Countries(index2).Country))
                        end
                    else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Deaths"
                            if app.OptionButtonGroup.SelectedObject.Text == "Cumulative" 
                                app.PlotData(app.Countries(index2).DataDeathCumu, sprintf(TempLoc, app.Countries(index2).Country))
                            else
                                app.PlotData(app.Countries(index2).DataDeathDaily, sprintf(TempLoc, app.Countries(index2).Country))
                            end
                    else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Both"
                            if app.OptionButtonGroup.SelectedObject.Text == "Cumulative" 
                                app.PlotData(app.Countries(index2).DataCumulative, sprintf(TempLoc, app.Countries(index2).Country), app.Countries(index2).DataDeathCumu)
                            else
                                app.PlotData(app.Countries(index2).DataDaily, sprintf(TempLoc, app.Countries(index2).Country), app.Countries(index2).DataDeathDaily)
                            end
 
                    end
                    end
                    end
                    
                end
                
            end
            
            if app.StateorRegionListBox.Value ~= "All"
                for i = 1:length(app.Countries)
                    for j = 1:length(app.Countries(i).States)
                        if isempty(app.Countries(i).States(j).Country)
                            break
                        else if app.Countries(i).States(j).Country == convertCharsToStrings(app.StateorRegionListBox.Value)
                            TempLoc = "in %s";
                            if app.DatatoPlotButtonGroup.SelectedObject.Text == "Cases"
                                if app.OptionButtonGroup.SelectedObject.Text == "Cumulative" 
                                    app.PlotData(app.Countries(i).States(j).DataCumulative, sprintf(TempLoc, app.Countries(i).States(j).Country));
                                    break    
                                else
                                    app.PlotData(app.Countries(i).States(j).DataDaily, sprintf(TempLoc, app.Countries(i).States(j).Country));
                                    break
                                end
                            else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Deaths"
                                    if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                                        app.PlotData(app.Countries(i).States(j).DataDeathCumu, sprintf(TempLoc, app.Countries(i).States(j).Country));
                                        break
                                    else
                                        app.PlotData(app.Countries(i).States(j).DataDeathDaily, sprintf(TempLoc, app.Countries(i).States(j).Country));
                                        break
                                    end
                            else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Both"
                                    if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                                        app.PlotData(app.Countries(i).States(j).DataCumulative, sprintf(TempLoc, app.Countries(i).States(j).Country), app.Countries(i).States(j).DataDeathCumu);
                                    else
                                        app.PlotData(app.Countries(i).States(j).DataDaily, sprintf(TempLoc, app.Countries(i).States(j).Country), app.Countries(i).States(j).DataDeathDaily);
                                    end
                            end
                            end
                            end
                        end
                        end
                    end
                end
            end


        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.OptionButtonGroup.SelectedObject;
            
                       
            if app.StateorRegionListBox.Value == "All"
                if app.CountryListBox.Value == "Global"
                    Gl = "globally";
                    if app.DatatoPlotButtonGroup.SelectedObject.Text == "Cases"
                        if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                            app.PlotData(app.GlobalCasesCumulative, Gl)
                        else
                            app.PlotData(app.GlobalCasesDaily, Gl)
                        end
                    else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Deaths"
                            if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                                app.PlotData(app.GlobalDeathCumulative, Gl)
                            else
                                app.PlotData(app.GlobalDeathDaily, Gl)
                            end
                    else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Both"
                            if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                                app.PlotData(app.GlobalCasesCumulative, Gl, app.GlobalDeathCumulative)
                            else
                                app.PlotData(app.GlobalCasesDaily, Gl, app.GlobalDeathDaily)
                            end
                            
                    end
                    end      
                    end   
                else
                    for i = 1:length(app.Countries)
                        if app.Countries(i).Country == app.CountryListBox.Value
                            index2 = i;
                        end
                    end
                    ListOfStates = ["All"];
                    ListOfStates = [ListOfStates app.Countries(index2).States.Country];
                    app.StateorRegionListBox.Items = ListOfStates;
                
                    TempLoc = "in %s";
                
                    if app.DatatoPlotButtonGroup.SelectedObject.Text == "Cases"
                        if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"    
                            app.PlotData(app.Countries(index2).DataCumulative, sprintf(TempLoc, app.Countries(index2).Country))
                        else
                            app.PlotData(app.Countries(index2).DataDaily, sprintf(TempLoc, app.Countries(index2).Country))
                        end
                    else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Deaths"
                            if app.OptionButtonGroup.SelectedObject.Text == "Cumulative" 
                                app.PlotData(app.Countries(index2).DataDeathCumu, sprintf(TempLoc, app.Countries(index2).Country))
                            else
                                app.PlotData(app.Countries(index2).DataDeathDaily, sprintf(TempLoc, app.Countries(index2).Country))
                            end
                    else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Both"
                            if app.OptionButtonGroup.SelectedObject.Text == "Cumulative" 
                                app.PlotData(app.Countries(index2).DataCumulative, sprintf(TempLoc, app.Countries(index2).Country), app.Countries(index2).DataDeathCumu)
                            else
                                app.PlotData(app.Countries(index2).DataDaily, sprintf(TempLoc, app.Countries(index2).Country), app.Countries(index2).DataDeathDaily)
                            end
 
                    end
                    end
                    end
                    
                end
                
            end
            
            if app.StateorRegionListBox.Value ~= "All"
                for i = 1:length(app.Countries)
                    for j = 1:length(app.Countries(i).States)
                        if isempty(app.Countries(i).States(j).Country)
                            break
                        else if app.Countries(i).States(j).Country == convertCharsToStrings(app.StateorRegionListBox.Value)
                            TempLoc = "in %s";
                            if app.DatatoPlotButtonGroup.SelectedObject.Text == "Cases"
                                if app.OptionButtonGroup.SelectedObject.Text == "Cumulative" 
                                    app.PlotData(app.Countries(i).States(j).DataCumulative, sprintf(TempLoc, app.Countries(i).States(j).Country));
                                    break    
                                else
                                    app.PlotData(app.Countries(i).States(j).DataDaily, sprintf(TempLoc, app.Countries(i).States(j).Country));
                                    break
                                end
                            else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Deaths"
                                    if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                                        app.PlotData(app.Countries(i).States(j).DataDeathCumu, sprintf(TempLoc, app.Countries(i).States(j).Country));
                                        break
                                    else
                                        app.PlotData(app.Countries(i).States(j).DataDeathDaily, sprintf(TempLoc, app.Countries(i).States(j).Country));
                                        break
                                    end
                            else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Both"
                                    if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                                        app.PlotData(app.Countries(i).States(j).DataCumulative, sprintf(TempLoc, app.Countries(i).States(j).Country), app.Countries(i).States(j).DataDeathCumu);
                                    else
                                        app.PlotData(app.Countries(i).States(j).DataDaily, sprintf(TempLoc, app.Countries(i).States(j).Country), app.Countries(i).States(j).DataDeathDaily);
                                    end
                            end
                            end
                            end
                        end
                        end
                    end
                end
            end


        end

        % Value changed function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanged(app, event)
            value = app.AveragedofdaysSlider.Value;
            app.AveragedofdaysSlider.Value = round(app.AveragedofdaysSlider.Value);
             if app.StateorRegionListBox.Value == "All"
                if app.CountryListBox.Value == "Global"
                    Gl = "globally";
                    if app.DatatoPlotButtonGroup.SelectedObject.Text == "Cases"
                        if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                            app.PlotData(app.GlobalCasesCumulative, Gl)
                        else
                            app.PlotData(app.GlobalCasesDaily, Gl)
                        end
                    else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Deaths"
                            if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                                app.PlotData(app.GlobalDeathCumulative, Gl)
                            else
                                app.PlotData(app.GlobalDeathDaily, Gl)
                            end
                    else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Both"
                            if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                                app.PlotData(app.GlobalCasesCumulative, Gl, app.GlobalDeathCumulative)
                            else
                                app.PlotData(app.GlobalCasesDaily, Gl, app.GlobalDeathDaily)
                            end
                            
                    end
                    end      
                    end   
                else
                    for i = 1:length(app.Countries)
                        if app.Countries(i).Country == app.CountryListBox.Value
                            index2 = i;
                        end
                    end
                    ListOfStates = ["All"];
                    ListOfStates = [ListOfStates app.Countries(index2).States.Country];
                    app.StateorRegionListBox.Items = ListOfStates;
                
                    TempLoc = "in %s";
                
                    if app.DatatoPlotButtonGroup.SelectedObject.Text == "Cases"
                        if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"    
                            app.PlotData(app.Countries(index2).DataCumulative, sprintf(TempLoc, app.Countries(index2).Country))
                        else
                            app.PlotData(app.Countries(index2).DataDaily, sprintf(TempLoc, app.Countries(index2).Country))
                        end
                    else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Deaths"
                            if app.OptionButtonGroup.SelectedObject.Text == "Cumulative" 
                                app.PlotData(app.Countries(index2).DataDeathCumu, sprintf(TempLoc, app.Countries(index2).Country))
                            else
                                app.PlotData(app.Countries(index2).DataDeathDaily, sprintf(TempLoc, app.Countries(index2).Country))
                            end
                    else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Both"
                            if app.OptionButtonGroup.SelectedObject.Text == "Cumulative" 
                                app.PlotData(app.Countries(index2).DataCumulative, sprintf(TempLoc, app.Countries(index2).Country), app.Countries(index2).DataDeathCumu)
                            else
                                app.PlotData(app.Countries(index2).DataDaily, sprintf(TempLoc, app.Countries(index2).Country), app.Countries(index2).DataDeathDaily)
                            end
 
                    end
                    end
                    end
                    
                end
                
            end
            
            if app.StateorRegionListBox.Value ~= "All"
                for i = 1:length(app.Countries)
                    for j = 1:length(app.Countries(i).States)
                        if isempty(app.Countries(i).States(j).Country)
                            break
                        else if app.Countries(i).States(j).Country == convertCharsToStrings(app.StateorRegionListBox.Value)
                            TempLoc = "in %s";
                            if app.DatatoPlotButtonGroup.SelectedObject.Text == "Cases"
                                if app.OptionButtonGroup.SelectedObject.Text == "Cumulative" 
                                    app.PlotData(app.Countries(i).States(j).DataCumulative, sprintf(TempLoc, app.Countries(i).States(j).Country));
                                    break    
                                else
                                    app.PlotData(app.Countries(i).States(j).DataDaily, sprintf(TempLoc, app.Countries(i).States(j).Country));
                                    break
                                end
                            else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Deaths"
                                    if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                                        app.PlotData(app.Countries(i).States(j).DataDeathCumu, sprintf(TempLoc, app.Countries(i).States(j).Country));
                                        break
                                    else
                                        app.PlotData(app.Countries(i).States(j).DataDeathDaily, sprintf(TempLoc, app.Countries(i).States(j).Country));
                                        break
                                    end
                            else if app.DatatoPlotButtonGroup.SelectedObject.Text == "Both"
                                    if app.OptionButtonGroup.SelectedObject.Text == "Cumulative"
                                        app.PlotData(app.Countries(i).States(j).DataCumulative, sprintf(TempLoc, app.Countries(i).States(j).Country), app.Countries(i).States(j).DataDeathCumu);
                                    else
                                        app.PlotData(app.Countries(i).States(j).DataDaily, sprintf(TempLoc, app.Countries(i).States(j).Country), app.Countries(i).States(j).DataDeathDaily);
                                    end
                            end
                            end
                            end
                        end
                        end
                    end
                end
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.Items = {'Item 1', 'Item 2', '', ''};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionLabel = uilabel(app.UIFigure);
            app.StateorRegionLabel.Text = {'State or'; 'Region:'; ''};
            app.StateorRegionListBox = uilistbox(app.UIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.DatatoPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.AveragedofdaysSliderLabel = uilabel(app.UIFigure);
            app.AveragedofdaysSliderLabel.Text = {'Averaged'; '# of days'};
            app.AveragedofdaysSlider = uislider(app.UIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanged, true);
            app.AveragedofdaysSlider.MinorTicks = [2 4 6 8 10 12 14];
            app.AveragedofdaysSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CovidDataVisualization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end