classdef Covid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        CountryListBoxLabel    matlab.ui.control.Label
        CountryListBox         matlab.ui.control.ListBox
        StateorListBoxLabel    matlab.ui.control.Label
        StateorListBox         matlab.ui.control.ListBox
        AveragedSliderLabel    matlab.ui.control.Label
        AveragedSlider         matlab.ui.control.Slider
        DatatoPlotButtonGroup  matlab.ui.container.ButtonGroup
        CasesButton            matlab.ui.control.RadioButton
        DeathsButton           matlab.ui.control.RadioButton
        BothButton             matlab.ui.control.RadioButton
        OptionButtonGroup      matlab.ui.container.ButtonGroup
        CumulativeButton       matlab.ui.control.RadioButton
        DailyButton            matlab.ui.control.RadioButton
        RegionLabel            matlab.ui.control.Label
        ofdaysLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
       covidData  % information inside of covid data
                   % starts with an array of country 
                   % which contain a label 
                   % and an array of state
                   % which contain an array of data
       datesForData % array of all the dates
       countryVector % array of all the countries
  
       
       valueVector % array containing the data being displayed
       valueAverages % array containing the average of the data displayed
    end
    
    methods (Access = private)
        
        function countries = setCountryArray(app)
            countries = string.empty(length(app.covidData) + 1,0);
            countries(1) = 'all';
            for i = 1: length(app.covidData)
                countries(i+1) = app.covidData(i).label;
                
            end
        end
        
        function plotInfo(app)
            currentCountry = app.CountryListBox.Value;        
            currentState = app.StateorListBox.Value;
            
            if (app.CasesButton.Value == 1)
               typeIndex =  1;
            elseif (app.DeathsButton.Value == 1)
               typeIndex = 2;
            else
                typeIndex = 3;
            end
            
            optionAccumulate = (app.CumulativeButton.Value == 1);
            
            
            % generate the title of the figure
            if optionAccumulate == true
                generatedTitle = 'Cumulative ' ;
            else
                generatedTitle = 'Daily ';
                
            end
            if typeIndex == 1
                generatedTitle = strcat( generatedTitle, ' cases for ');
                ylabel(app.UIAxes, 'cases');
            elseif typeIndex == 2
                generatedTitle = strcat( generatedTitle, ' deaths for ');
                ylabel(app.UIAxes, 'deaths');
            else
                generatedTitle = strcat( generatedTitle, ' cases and deaths for ');
                ylabel(app.UIAxes, 'cases and deaths');
            end
            
            if strcmp(currentCountry,'all') == 1
                generatedTitle = strcat( generatedTitle, ' all countries ');
            else
                generatedTitle = strcat(generatedTitle, ' ');
                generatedTitle = strcat(generatedTitle, currentCountry);
                generatedTitle = strcat(generatedTitle, ' ');
            end
            
            if strcmp(currentState, 'total') == 0 && strcmp(currentState, ' ') == 0
                generatedTitle = strcat(generatedTitle, ' and ');
                generatedTitle = strcat(generatedTitle,currentState);
            end
            
            title(app.UIAxes, generatedTitle);
            
            % plot the actual graphs
            newcolors = [0 0.5 1; 0.5 0 1 ];
            colororder(app.UIAxes,newcolors);
            if typeIndex ==1 
                ylim(app.UIAxes, [min(app.valueVector(:, 1)) max(app.valueVector(:, 1))]);
                area(app.UIAxes, app.datesForData, app.valueVector(:, 1));
                if app.AveragedSlider.Value >1
                    hold(app.UIAxes, "on");
                    plot(app.UIAxes, app.datesForData, app.valueAverages(:,1)...
                        ,'y','LineWidth',2);
                    hold(app.UIAxes, "off");
                    
                end
            elseif typeIndex == 2
                 ylim(app.UIAxes, [min(app.valueVector(:, 2)) max(app.valueVector(:, 2))]);
                 area(app.UIAxes, app.datesForData, app.valueVector(:, 2) );
                 if app.AveragedSlider.Value >1
                    hold(app.UIAxes, "on");
                    plot(app.UIAxes, app.datesForData, app.valueAverages(:,2)...
                        ,'k','LineWidth',2);
                    hold(app.UIAxes, "off");
                    
                end
            else
                ylim(app.UIAxes, [min(app.valueVector(:, 1)) max(app.valueVector(:, 1))]);
                area(app.UIAxes, app.datesForData, app.valueVector(:, 1));
                hold(app.UIAxes, 'on');
                area(app.UIAxes, app.datesForData, app.valueVector(:,2) );
                if app.AveragedSlider.Value >1
                    
                    plot(app.UIAxes,app.datesForData, app.valueAverages(:,1)...
                        ,'y','LineWidth',2);
                    plot(app.UIAxes,app.datesForData, app.valueAverages(:,2)...
                        ,'k','LineWidth',2);
                    
                end
                hold(app.UIAxes, 'off');
                legend(app.UIAxes, {'cases', 'deaths'});
            end


        end
        
        function results = totalForCountryAndState(app, countryIndex, accumulate, stateIndex)
            results = zeros(length(app.datesForData),2);
        
            if accumulate == true
             
                for dateIndex = 2:length(app.datesForData)
                    if stateIndex == -1
                        results(dateIndex, 1) = sum(app.covidData(countryIndex).stateValuesCumulative(:,dateIndex,1));
                        results(dateIndex, 2) = sum(app.covidData(countryIndex).stateValuesCumulative(:,dateIndex,1));
                    else
                        results(dateIndex, 1) = app.covidData(countryIndex).stateValuesCumulative(stateIndex,dateIndex,1);
                        results(dateIndex, 2) = app.covidData(countryIndex).stateValuesCumulative(stateIndex,dateIndex,2);
                    end
                end
            else
                
                for dateIndex = 1:length(app.datesForData)
                    if stateIndex == -1
                        results(dateIndex,1) = sum(app.covidData(countryIndex).stateValues(:,dateIndex,1));
                        results(dateIndex, 2) = sum(app.covidData(countryIndex).stateValues(:,dateIndex,2));
                
                    else
                        results(dateIndex, 1) = app.covidData(countryIndex).stateValues(stateIndex,dateIndex,1);
                        results(dateIndex, 2) = app.covidData(countryIndex).stateValues(stateIndex,dateIndex,2);
                    
                    end
      
                end
            end
            %results
        end


        function  generateStateList(app, currentCountry)
            if strcmp(currentCountry, 'all') == 1 % we went back to all of them
                app.StateorListBox.Items = {' '};
            else     
           
                [~, currentCountryIndex] = ismember(currentCountry, app.CountryListBox.Items);
                 % the index is off by one because of the "all"         
                 app.StateorListBox.Items =app.covidData(currentCountryIndex -1).stateArrayLabels(:);
            end
        end
        
        function RefreshData(app)
            currentCountry = app.CountryListBox.Value;        
            currentState = app.StateorListBox.Value;
            
            
            optionAccumulate = (app.CumulativeButton.Value == 1);
            
            
            if strcmp (currentCountry, 'all') == 1
       
                 % we need to accumulate all countries
                
                 app.valueVector = app.totalForCountryAndState(1, optionAccumulate, -1);
                 for i = 2:length(app.covidData(:,1))
                               
                   app.valueVector = app.totalForCountryAndState(i,optionAccumulate, -1) ...
                      + app.valueVector;
                   
                 end
            else % single country selected
                [~, currentCountryIndex] = ismember(currentCountry, app.CountryListBox.Items);
                % we need to check the states
                if strcmp(currentState,'total') == 1
                    currentStateIndex = -1; % -1 will be all
                else 
                    [~, currentStateIndex] = ismember(currentState, app.StateorListBox.Items);
                end
                app.valueVector = app.totalForCountryAndState(currentCountryIndex - 1, optionAccumulate, currentStateIndex);
            end
            avgValue = app.AveragedSlider.Value;
            if avgValue > 1
                app.valueAverages(:,1)= movmean(app.valueVector(:,1),avgValue); % cases
                app.valueAverages(:,2)= movmean(app.valueVector(:,2),avgValue); % deaths
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartUp(app)
              load covid_data.mat covid_data;
              [app.covidData, app.datesForData] = loadData(covid_data);
              % modify the dates on the x axis
              
              dateStart = app.datesForData(1);
              dateEnd = app.datesForData(end);
              
              xlim(app.UIAxes, [dateStart dateEnd]);
              datetick(app.UIAxes,'x','mm-dd-yyyy');
              
              % load the countries in the CountryListBox
              app.countryVector = setCountryArray(app);
              app.CountryListBox.Items = app.countryVector;
              app.valueVector = zeros(length(app.datesForData),2);
              app.valueAverages = zeros(length(app.datesForData),2);
       
              % to start with, all the states will be blank
              app.StateorListBox.Items = " ";
              app.RefreshData();
              app.plotInfo();
              disp("breakpoint for testing");
        end

        % Value changed function: CountryListBox
        function countryChanged(app, event)
            value = app.CountryListBox.Value;
            
            %change the listbox content of the state
            app.generateStateList(value);
            app.RefreshData();
            app.plotInfo();
        end

        % Value changed function: StateorListBox
        function stateChanged(app, event)
            app.RefreshData();
            app.plotInfo();
        end

        % Selection changed function: DatatoPlotButtonGroup
        function CaseChanged(app, event)
            app.plotInfo();
        end

        % Selection changed function: OptionButtonGroup
        function cumChanged(app, event)
            app.RefreshData();
            app.plotInfo();
            
        end

        % Value changed function: AveragedSlider
        function AverageChanged(app, event)
            avgValue = floor(app.AveragedSlider.Value);
            if avgValue > 1
                app.valueAverages(:, 1)= movmean(app.valueVector(:,1),avgValue); % cases
                app.valueAverages(:, 2)= movmean(app.valueVector (:,2),avgValue); % deaths
            end
            app.plotInfo(); % data does not need to be recomputed
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'dates')
            ylabel(app.UIAxes, 'cases')
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @countryChanged, true);
            app.StateorListBoxLabel = uilabel(app.UIFigure);
            app.StateorListBoxLabel.Text = 'State or ';
            app.StateorListBox = uilistbox(app.UIFigure);
            app.StateorListBox.ValueChangedFcn = createCallbackFcn(app, @stateChanged, true);
            app.AveragedSliderLabel = uilabel(app.UIFigure);
            app.AveragedSliderLabel.Text = 'Averaged';
            app.AveragedSlider = uislider(app.UIFigure);
            app.AveragedSlider.Limits = [1 15];
            app.AveragedSlider.ValueChangedFcn = createCallbackFcn(app, @AverageChanged, true);
            app.AveragedSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.AveragedSlider.Value = 1;
            app.DatatoPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CaseChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @cumChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.RegionLabel = uilabel(app.UIFigure);
            app.RegionLabel.Text = 'Region:';
            app.ofdaysLabel = uilabel(app.UIFigure);
            app.ofdaysLabel.Text = '# of days';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Covid

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end